classdef GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportNeuroKitFigureButton      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SparseCheckBox                  matlab.ui.control.CheckBox
        FiletoreadDropDown              matlab.ui.control.DropDown
        FiletoreadDropDownLabel         matlab.ui.control.Label
        FileBrowserButton               matlab.ui.control.Button
        PlotPreprocessedSignalCheckBox  matlab.ui.control.CheckBox
        PlotPhasicCheckBox              matlab.ui.control.CheckBox
        PlotTonicCheckBox               matlab.ui.control.CheckBox
        ResetTimesButton                matlab.ui.control.Button
        InsertTimeButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        Timesofstimulation              matlab.ui.control.EditField
        TimesofstimulationhhmmssEditFieldLabel  matlab.ui.control.Label
        EDAProcessingPanel              matlab.ui.container.Panel
        NeurokitCheckBox                matlab.ui.control.CheckBox
        SCRMethod                       matlab.ui.control.DropDown
        FindSCRMethodLabel              matlab.ui.control.Label
        DecompMethod                    matlab.ui.control.DropDown
        TonicandPhasicDecompositionMethodDropDownLabel  matlab.ui.control.Label
        PreProcessingPanel              matlab.ui.container.Panel
        WindowDropDown                  matlab.ui.control.DropDown
        WindowDropDownLabel             matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        SelectcutoffFrequencySlider     matlab.ui.control.Slider
        SelectcutoffFrequencySliderLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fs=3;
        DirPath
        %['C:\Lilach\Technion\Project A']
        %['C:\Users\alonz\OneDrive - Technion\תואר\סמסטר 6\פרויקט\' ...
        %        'project - Stress Detection with a Smart Ring\Ring Samples\CSV'];   
        Time
        secsFromStart
        raw
        acc
        st
        ii
        MM
        SCR
        stimulationTimes = NaT(1);


    end
    
    methods (Access = private)
        function  modifiedPlot(app, axes, preData, tonic, phasic, tEDA_peaks,ampEDA_peaks,titleName)

            cla(axes);
            if app.PlotPreprocessedSignalCheckBox.Value
                plot(axes,app.Time,preData,'DisplayName', ...
                    'Preproccesed Data','Color','blue','LineWidth',1.5); hold(axes,'on')
            end
            if app.PlotTonicCheckBox.Value
                plot(axes,app.Time,tonic,'DisplayName','Tonic','LineWidth',1.5, 'Color','red'); hold(axes,'on')
            end
            if app.PlotPhasicCheckBox.Value
                plot(axes,app.Time,phasic + min([preData(:);tonic(:)]) -0.1,'DisplayName','Phasic','LineWidth',1.5,'Color','green'); hold(axes,'on')
                if strcmp(titleName,'Neurokit')
                    plot(axes,tEDA_peaks,ampEDA_peaks + min([preData(:);tonic(:)]) -0.1 ,'o','DisplayName','SCR','LineWidth',1.5,'Color',[0.5 0 0.4]);
                end
            end

            if ~all(isnat(app.stimulationTimes))
                tStim = timeofday(datetime(app.stimulationTimes(2:end),'InputFormat','HH:mm:ss'));
                xline(axes,tStim,'DisplayName','Event');
            end
            grid(axes,'on');
            legend(axes);
            xlabel(axes,'Time', "FontSize", 12)
            ylabel(axes,'EDA [\muS]', "FontSize", 12)
            title(axes,titleName, "FontSize", 20);
            hold(axes,'off')
            xlim(axes,[app.Time(1),app.Time(end)]);
            ylim(axes,[min([preData(:);tonic(:)]) - 0.1 ,  max([preData(:); tonic(:)]) + 0.2]);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            
        end

        % Value changed function: FiletoreadDropDown
        function FiletoreadDropDownValueChanged(app, event)
            %read data again:
            fileName = app.FiletoreadDropDown.Value;
            filePath = append(app.DirPath,append('\',fileName));
            T = readtable(filePath);
            cut = 50;
            app.Time = timeofday(T.date_time);
            app.Time = app.Time(cut:end);
            app.secsFromStart = T.time_sec_(cut:end);
            app.raw = T.raw(cut:end);
            app.acc = [T.ax, T.ay, T.az];
            app.st = T.st;
            app.ii = T.ii;
            app.MM = T.mm;
            app.SCR = T.scr;
            %reset the stimulation times array:
            app.stimulationTimes = NaT(1);
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %preprocess:
            order = app.FilterOrderEditField.Value;
            cutoffFreq = app.SelectcutoffFrequencySlider.Value;
            preProccesedData = preprocess(app.raw,app.fs,order,cutoffFreq,app.WindowDropDown.Value);

            %acceleration:
            a(:,1)= (app.acc(:,1)-128)/64;
            a(:,2) = (app.acc(:,2)-128)/64;
            a(:,3) = (app.acc(:,3)-128)/64;
            aT = sqrt(a(:,1).^2+a(:,2).^2+a(:,3).^2);
            
            figure;
            axes = gca;

            if app.NeurokitCheckBox.Value == true
                %extract tonic and phasic components:
                phasicMethod = app.DecompMethod.Value;
                tonic_1 = pyrunfile('neurokitMatlab.py', "tonic", ...
                    eda_signal = preProccesedData, phasicMethod = phasicMethod, peakMethod='');
                phasic_1 = pyrunfile('neurokitMatlab.py', "phasic", ...
                    eda_signal = preProccesedData, phasicMethod = phasicMethod, peakMethod='');
                % transfer from pylist to matlab array:
                tonic_1 = double(tonic_1);
                phasic_1 = double(phasic_1);
    
                %find peaks:
                peakMethod = app.SCRMethod.Value;
                EDA_peaks = pyrunfile('neurokitMatlab.py', "EDA_peaks", ...
                    eda_signal = phasic_1, phasicMethod = '',peakMethod=peakMethod);
                %find the time and amplitude of the peaks to plot them:
                tEDA_peaks = app.Time(double(EDA_peaks)+1+1*strcmp(peakMethod,'Kim2004'));
                ampEDA_peaks = phasic_1(double(EDA_peaks)+1+1*strcmp(peakMethod,'Kim2004'));

                modifiedPlot(app, axes,preProccesedData,tonic_1,phasic_1, ...
                    tEDA_peaks,ampEDA_peaks,'Neurokit')
            end
            if app.SparseCheckBox.Value == true
                sr = 3; %sample rate
                graphics = 0;
                epsilon  = 1e-3;
                Kmax   = 500;
                dmin = 1.25*sr;  % Minimum distance between activations
                rho = 0.025;
                [phasic_2, tonic_2, MSE] = sparsEDA(preProccesedData,sr,graphics,epsilon,Kmax,dmin,rho);
                phasic_2 = phasic_2(1:end-1);
                tonic_2 = tonic_2(1:end-1);
                figure;
                axes2 = gca;
                modifiedPlot(app, axes2,preProccesedData,tonic_2,phasic_2,[],[],'SparsEDA')
            end
            % linkaxes([app.UIAxes2,app.UIAxes1])

            %plot:


        end

        % Button pushed function: InsertTimeButton
        function InsertTimeButtonPushed(app, event)
            stimulationTime = datetime(app.Timesofstimulation.Value);
            app.stimulationTimes(end+1) = stimulationTime;
            app.Timesofstimulation.Value = '';


        end

        % Button pushed function: ResetTimesButton
        function ResetTimesButtonPushed(app, event)
            app.stimulationTimes = NaT(1);
        end

        % Button pushed function: FileBrowserButton
        function FileBrowserButtonPushed(app, event)
            app.DirPath = uigetdir(['C:\Users\alonz\OneDrive - Technion\תואר\פרויקט\project' ...
                ' - Stress Detection with a Smart Ring\ניסוי\Experiment Results']  );

            filesList = dir(app.DirPath);
            filesNames = strings;
            
            for jj = 1:(length(filesList)-2)
                filesNames(end+1,:) = filesList(jj+2).name;
            end
            filesNames = sort(filesNames(2:end));
            app.FiletoreadDropDown.Items = filesNames;
            FiletoreadDropDownValueChanged(app);
            
        end

        % Value changed function: SparseCheckBox
        function SparseCheckBoxValueChanged(app, event)
            % value = app.SparseCheckBox.Value;
            % if value == true
            %     app.NeurokitCheckBox.Value = false;
            % end
        end

        % Value changed function: NeurokitCheckBox
        function NeurokitCheckBoxValueChanged(app, event)
            % value = app.NeurokitCheckBox.Value;
            % if value == true
            %     app.SparseCheckBox.Value = false;
            % end
        end

        % Button pushed function: ExportNeuroKitFigureButton
        function ExportNeuroKitFigureButtonPushed(app, event)
            h = figure;
            h.Visible = 'on';
            x = app.UIAxes1.XAxis.Parent.Children.XData;
            y = app.UIAxes1.XAxis.Parent.Children.YData;
            plot(x,y)
            lgndName1 = app.UIAxes1.Legend.String{1};
            lgd = legend(lgndName1);
            lgd.Box = app.UIAxes1.Legend.Box;
            lgd.Location = app.UIAxes1.Legend.Location;
            h.CurrentAxes.YLabel.String = app.UIAxes1.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = app.UIAxes1.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = app.UIAxes1.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = app.UIAxes1.XLabel.FontSize;
            h.CurrentAxes.Title.String = app.UIAxes1.Title.String;
            h.CurrentAxes.Title.FontSize = app.UIAxes1.Title.FontSize;
            h.CurrentAxes.XLim = [0 max(x)];
            h.CurrentAxes.XLim = [0 max(y)+1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PreProcessingPanel = uipanel(app.UIFigure);
            app.PreProcessingPanel.Title = 'PreProcessing';
            app.SelectcutoffFrequencySliderLabel = uilabel(app.PreProcessingPanel);
            app.SelectcutoffFrequencySliderLabel.Text = 'Select cutoff Frequency';
            app.SelectcutoffFrequencySlider = uislider(app.PreProcessingPanel);
            app.SelectcutoffFrequencySlider.Limits = [0.1 1];
            app.SelectcutoffFrequencySlider.MajorTicks = [0.1 0.5 1];
            app.SelectcutoffFrequencySlider.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SelectcutoffFrequencySlider.Value = 0.5;
            app.FilterOrderEditFieldLabel = uilabel(app.PreProcessingPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.PreProcessingPanel, 'numeric');
            app.FilterOrderEditField.Value = 8;
            app.WindowDropDownLabel = uilabel(app.PreProcessingPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.PreProcessingPanel);
            app.WindowDropDown.Items = {'Bartlett', 'Hamming', 'Blackman', 'Gaussian', 'Hann'};
            app.WindowDropDown.Value = 'Hamming';
            app.EDAProcessingPanel = uipanel(app.UIFigure);
            app.EDAProcessingPanel.Title = 'EDA Processing';
            app.TonicandPhasicDecompositionMethodDropDownLabel = uilabel(app.EDAProcessingPanel);
            app.TonicandPhasicDecompositionMethodDropDownLabel.Text = 'Tonic and Phasic Decomposition Method';
            app.DecompMethod = uidropdown(app.EDAProcessingPanel);
            app.DecompMethod.Items = {'Smooth Median', 'High Pass', 'Convex'};
            app.DecompMethod.Value = 'Convex';
            app.FindSCRMethodLabel = uilabel(app.EDAProcessingPanel);
            app.FindSCRMethodLabel.Text = 'Find SCR Method';
            app.SCRMethod = uidropdown(app.EDAProcessingPanel);
            app.SCRMethod.Items = {'NeuroKit', 'Kim2004', 'Nabian2018', 'Gamboa2008', 'Vanhalem2020'};
            app.SCRMethod.Value = 'NeuroKit';
            app.NeurokitCheckBox = uicheckbox(app.EDAProcessingPanel);
            app.NeurokitCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeurokitCheckBoxValueChanged, true);
            app.NeurokitCheckBox.Text = 'NeuroKit';
            app.NeurokitCheckBox.Value = true;
            app.TimesofstimulationhhmmssEditFieldLabel = uilabel(app.UIFigure);
            app.TimesofstimulationhhmmssEditFieldLabel.Text = 'Times of stimulation (hh:mm:ss)';
            app.Timesofstimulation = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InsertTimeButton = uibutton(app.UIFigure, 'push');
            app.InsertTimeButton.ButtonPushedFcn = createCallbackFcn(app, @InsertTimeButtonPushed, true);
            app.InsertTimeButton.Text = 'Insert Time';
            app.ResetTimesButton = uibutton(app.UIFigure, 'push');
            app.ResetTimesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimesButtonPushed, true);
            app.ResetTimesButton.Text = 'Reset Times';
            app.PlotTonicCheckBox = uicheckbox(app.UIFigure);
            app.PlotTonicCheckBox.Text = 'Plot Tonic';
            app.PlotTonicCheckBox.Value = true;
            app.PlotPhasicCheckBox = uicheckbox(app.UIFigure);
            app.PlotPhasicCheckBox.Text = 'Plot Phasic';
            app.PlotPhasicCheckBox.Value = true;
            app.PlotPreprocessedSignalCheckBox = uicheckbox(app.UIFigure);
            app.PlotPreprocessedSignalCheckBox.Text = 'Plot Preprocessed Signal';
            app.PlotPreprocessedSignalCheckBox.Value = true;
            app.FileBrowserButton = uibutton(app.UIFigure, 'push');
            app.FileBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @FileBrowserButtonPushed, true);
            app.FileBrowserButton.Text = 'File Browser';
            app.FiletoreadDropDownLabel = uilabel(app.UIFigure);
            app.FiletoreadDropDownLabel.Text = 'File to read';
            app.FiletoreadDropDown = uidropdown(app.UIFigure);
            app.FiletoreadDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletoreadDropDownValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.SparseCheckBox = uicheckbox(app.Panel);
            app.SparseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SparseCheckBoxValueChanged, true);
            app.SparseCheckBox.Text = 'SparsEDA';
            app.SparseCheckBox.Value = true;
            app.ExportNeuroKitFigureButton = uibutton(app.UIFigure, 'push');
            app.ExportNeuroKitFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNeuroKitFigureButtonPushed, true);
            app.ExportNeuroKitFigureButton.Text = 'Export NeuroKit Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end